classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CircuitVisionUIFigure    matlab.ui.Figure
        Button                   matlab.ui.control.Button
        CaptureFromCameraButton  matlab.ui.control.Button
        CircuitComponentClassifierImportProcessVisualizewithCNNLabel  matlab.ui.control.Label
        ClickButtonbelowtoimportImageORopencameraandfeedliveimageLabel  matlab.ui.control.Label
        AfterImportingImageVisualizeeachlayerofCNNModelLabel  matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        VisualizeButton          matlab.ui.control.Button
        ImportImageButton        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         ImagePath
         ModelPath = 'circuit_component_classifier.mat'
        
    end
    
        methods (Access = private)
        
        function testModelOnImage(app)
            if isempty(app.ImagePath)
                app.StatusLabel.Text = 'Please import an image first';
                return;
            end
            
            try
                app.StatusLabel.Text = 'Processing image and testing model...';
                drawnow;
                
                result = processAndClassifyImage(app.ImagePath, app.ModelPath);
                
                app.StatusLabel.Text = ['Classification result: ' result];
            catch e
                app.StatusLabel.Text = ['Error: ' e.message];
            end
        end

        
        function visualizeNetworkAct(app)
            if isempty(app.ImagePath)
                app.StatusLabel.Text = 'Please import an image first';
                return;
            end
            
            try
                app.StatusLabel.Text = 'Visualizing network activations...';
                drawnow;
                
                visualizeActivations(app.ModelPath, app.ImagePath);
                
                app.StatusLabel.Text = 'Activations visualized in separate figure';
            catch e
                app.StatusLabel.Text = ['Error: ' e.message];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'});
            if file ~= 0
                originalPath = fullfile(path, file);
        
                img = imread(originalPath);
                imshow(img, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Imported Image');
        
                try
                    processedPath = convertToBW(originalPath);
                    app.ImagePath = processedPath;
                    processedImg = imread(processedPath);
                    figure;
                    imshow(processedImg);
                    title('Black & White Image for Classification');
        
                    app.StatusLabel.Text = 'Image converted to BW and ready for classification';
                catch e
                    app.StatusLabel.Text = ['Error during conversion: ' e.message];
                end
            end
        end

        % Callback function
        function TestModelonImageButtonPushed(app, event)
           
            app.testModelOnImage();
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
      
            try
                app.visualizeNetworkAct();
            catch e
                app.StatusLabel.Text = ['Error: ' e.message];
            end
        end

        % Button pushed function: CaptureFromCameraButton
        function CaptureFromCameraButtonPushed(app, event)
            try
                app.StatusLabel.Text = 'Opening camera...';
                drawnow;
        
                cam = webcam;
                preview(cam);
        
                pause(5);
        
                img = snapshot(cam);
                clear cam;


                tempFile = fullfile(tempdir, 'captured_image.jpg');
                imwrite(img, tempFile);
        
               
                processedPath = convertToBW(tempFile);
                app.ImagePath = processedPath;
        
               
                imshow(imread(processedPath), 'Parent', app.UIAxes);
                title(app.UIAxes, 'Captured and Converted Image');
        
                app.StatusLabel.Text = 'Image captured, converted to BW, and ready for classification';
        
            catch e
                app.StatusLabel.Text = ['Camera error: ' e.message];
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             
            feedbackFig = uifigure('Name', 'Feedback & Contact', 'Position', [500 400 450 250]);


            uilabel(feedbackFig, ...
                'Position', [25 140 400 90], ...
                'Text', ['Thank you for using Circuit Vision. As a student developer, I welcome your feedback and suggestions.', newline, ...
                         'Feel free to explore the project on GitHub or contact me via email.'], ...
                'FontSize', 14, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center', ...
                'WordWrap', 'on');
        
      
            uibutton(feedbackFig, 'push', ...
                'Position', [75 60 120 30], ...
                'Text', 'View on GitHub', ...
                'ButtonPushedFcn', @(btn, event) web('https://github.com/Kaushikmak/CircuitVision', '-browser'), ...
                'BackgroundColor', [0.2 0.6 1], ...
                'FontWeight', 'bold');
        
   
            uibutton(feedbackFig, 'push', ...
                'Position', [250 60 120 30], ...
                'Text', 'Contact via Email', ...
                'ButtonPushedFcn', @(btn, event) web('mailto:kaushikmak35@gmail.com', '-browser'), ...
                'BackgroundColor', [0.2 0.8 0.4], ...
                'FontWeight', 'bold');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CircuitVisionUIFigure = uifigure('Visible', 'off');
            app.CircuitVisionUIFigure.Name = 'Circuit Vision';
            app.CircuitVisionUIFigure.Icon = fullfile(pathToMLAPP, 'circuit.png');
            app.UIAxes = uiaxes(app.CircuitVisionUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportImageButton = uibutton(app.CircuitVisionUIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Icon = fullfile(pathToMLAPP, 'import.png');
            app.ImportImageButton.Text = 'Import Image';
            app.VisualizeButton = uibutton(app.CircuitVisionUIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize ';
            app.StatusLabel = uilabel(app.CircuitVisionUIFigure);
            app.StatusLabel.Text = 'Status';
            app.AfterImportingImageVisualizeeachlayerofCNNModelLabel = uilabel(app.CircuitVisionUIFigure);
            app.AfterImportingImageVisualizeeachlayerofCNNModelLabel.Text = 'After Importing Image Visualize each layer of CNN Model';
            app.ClickButtonbelowtoimportImageORopencameraandfeedliveimageLabel = uilabel(app.CircuitVisionUIFigure);
            app.ClickButtonbelowtoimportImageORopencameraandfeedliveimageLabel.Text = {'Click Button below to import Image'; 'OR open camera and feed live image'};
            app.CircuitComponentClassifierImportProcessVisualizewithCNNLabel = uilabel(app.CircuitVisionUIFigure);
            app.CircuitComponentClassifierImportProcessVisualizewithCNNLabel.Text = 'Circuit Component Classifier: Import, Process & Visualize with CNN';
            app.CaptureFromCameraButton = uibutton(app.CircuitVisionUIFigure, 'push');
            app.CaptureFromCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureFromCameraButtonPushed, true);
            app.CaptureFromCameraButton.Icon = fullfile(pathToMLAPP, 'camera.png');
            app.CaptureFromCameraButton.Text = 'CaptureFromCamera';
            app.Button = uibutton(app.CircuitVisionUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'information-mark.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.CircuitVisionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CircuitVisionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CircuitVisionUIFigure)
        end
    end
end